classdef demo_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Panel         matlab.ui.container.Panel
        LSBAPPLabel   matlab.ui.control.Label
        Panel_2       matlab.ui.container.Panel
        EditField     matlab.ui.control.NumericEditField
        Label_3       matlab.ui.control.Label
        Label_2       matlab.ui.control.Label
        ReadButton    matlab.ui.control.Button
        WriteButton   matlab.ui.control.Button
        SeleteButton  matlab.ui.control.Button
        UIAxes        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        carry % 图片，用于写信息
    end
    
    methods (Access = private)
        
        function C_M = lsbWrite(app,key,message_path)
            % 照搬实时函数的内容，可以参考实时函数理解
            C_M= app.carry;
            fileID=fopen(message_path,'r');
            [message,L]=fread(fileID,'ubit1');
            [row,col,channel,layer]=randEmbedding(app.carry,L,key);
            L32 = uint32(L);
            for k=1:32
               i = row(k);j = col(k);ch = channel(k);l = layer(k);
               C_M(i,j,ch) = bitset(C_M(i,j,ch),l,bitget(L32,k)); 
            end
            for k = 1:L
                i = row(k+32);j = col(k+32);ch = channel(k+32);l = layer(k+32);m = message(k);
                try
                    C_M(i,j,ch) = bitset(C_M(i,j,ch),l,m);
                catch
                    disp("ERROR")
                end
            end
            return 
        end
        
        function lsbRead(app,C_M, key)
            % 照搬实时函数的内容，可以参考实时函数理解
            [height,width,~]=size(C_M);
            rng(key);
            set = zeros(height*width*3*2,4); % 待随机抽取的位置集合
            cnt = 1;
            for i = 1:height
                for j = 1:width
                    for k = 1:3
                        for l = 1:2
                            set(cnt,:) = [i,j,k,l];
                            cnt = cnt + 1;
                        end
                    end
                end
            end
            
            index = randperm(height*width*3*2);
            index_L = index(1:32);
            row_L = set(index_L,1);
            col_L = set(index_L,2);
            channel_L = set(index_L,3);
            layer_L = set(index_L,4);
            L32 = uint32(0);
            for k=1:32
               i = row_L(k);j = col_L(k);ch = channel_L(k);l = layer_L(k);
               L32 = bitset(L32,k,bitget(C_M(i,j,ch),l)); 
            end
            M = zeros(L32,1);
            index_D = index(33:32+L32);
            row = set(index_D,1);
            col = set(index_D,2);
            channel = set(index_D,3);
            layer = set(index_D,4);
            for k = 1:L32
                i = row(k);j = col(k);ch = channel(k);l = layer(k);
                try
                    M(k) = bitget(C_M(i,j,ch),l);
                catch
                    disp("ERROR")
                end
            end
            % 保存文件和实时函数有差异：这里可以保存任何类型的文件，但问题在于，接收方需要预先知道文件的类型
            % 如果之后有人看到这段注释，可以参考我添加文件长度的方式把后缀信息也嵌进二进制文件首部，这里我就直接假设接收方知道文件类型了
            [filename, filedir] = uiputfile('*','保存提取的信息【注意存储格式哦】');          
            filepath = strcat(filedir, filename);
            fileID = fopen(filepath,'w');
            fwrite(fileID,M,'ubit1');
                        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SeleteButton
        function SeleteButtonPushed(app, event)
            % 打开窗口，选择载体文件
            [filename, filedir] = uigetfile('*.bmp','选择载体图片');
            filepath = strcat(filedir, filename);

            app.carry = imread(filepath,"bmp");
            imshow(app.carry,'Parent',app.UIAxes);           
        end

        % Button pushed function: WriteButton
        function WriteButtonPushed(app, event)
            % 利用函数将信息写进图片并保存图片
            % 1. 读取写入文件的路径
            [filename, filedir] = uigetfile('*','写入文件');
            filepath = strcat(filedir, filename);
           
            %2. 读入key
            key = app.EditField.Value;
            %3. 写入message
            C_M=app.lsbWrite(key,filepath);
            % 存储
            [filename2, filedir2] = uiputfile('*','保存写入信息后的图片'); % 注意该函数只能获得路径，不能保存
            filepath2 = strcat(filedir2,filename2);
            imwrite(C_M,filepath2);

        end

        % Button pushed function: ReadButton
        function ReadButtonPushed(app, event)
            [filename, filedir] = uigetfile('*','选择需要读取信息的图片');
            filepath = strcat(filedir, filename);
            image_read = imread(filepath);
            key = app.EditField.Value;
            app.lsbRead(image_read,key);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.Panel_2 = uipanel(app.UIFigure);
            app.SeleteButton = uibutton(app.Panel_2, 'push');
            app.SeleteButton.ButtonPushedFcn = createCallbackFcn(app, @SeleteButtonPushed, true);
            app.SeleteButton.Text = '选择载体图像';
            app.WriteButton = uibutton(app.Panel_2, 'push');
            app.WriteButton.ButtonPushedFcn = createCallbackFcn(app, @WriteButtonPushed, true);
            app.WriteButton.Text = '写入信息';
            app.ReadButton = uibutton(app.Panel_2, 'push');
            app.ReadButton.ButtonPushedFcn = createCallbackFcn(app, @ReadButtonPushed, true);
            app.ReadButton.Text = '读取信息';
            app.Label_2 = uilabel(app.Panel_2);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.Panel_2);
            app.Label_3.Text = '密钥';
            app.EditField = uieditfield(app.Panel_2, 'numeric');
            app.Panel = uipanel(app.UIFigure);
            app.LSBAPPLabel = uilabel(app.Panel);
            app.LSBAPPLabel.Text = '基于随机LSB算法的图片隐写APP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demo_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end